classdef guiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SettingsPanel             matlab.ui.container.Panel
        DownloadimageButton       matlab.ui.control.StateButton
        StartButton               matlab.ui.control.StateButton
        haarlengthEditFieldLabel  matlab.ui.control.Label
        haarlengthEditField       matlab.ui.control.NumericEditField
        statrpointEditFieldLabel  matlab.ui.control.Label
        statrpointEditField       matlab.ui.control.NumericEditField
        epsilonEditFieldLabel     matlab.ui.control.Label
        epsilonEditField          matlab.ui.control.NumericEditField
        stepsEditFieldLabel       matlab.ui.control.Label
        stepsEditField            matlab.ui.control.NumericEditField
        PreSettingButton          matlab.ui.control.Button
        jumpcoefEditFieldLabel    matlab.ui.control.Label
        jumpcoefEditField         matlab.ui.control.NumericEditField
        ROWSpinnerLabel           matlab.ui.control.Label
        ROWSpinner                matlab.ui.control.Spinner
        CheckBox                  matlab.ui.control.CheckBox
        InvertLabel               matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
       FIG_LIB
       FIG_LIB_LEN
       e_diff 
       wave_step
       jump_coef 
       max_iter
       MM_direction
       conv_wave_step
       max_point_in_line
       x_offset
       signal_max_value
       show_jump
       Pict
       
       ROW
       y_dim
    end

    methods (Access = private)
    
        function init(app)

            [app.FIG_LIB, app.FIG_LIB_LEN]  = FLIB(  );

              app.e_diff       = 1.001;  
              app.wave_step    = 7;
              app.jump_coef    = 0.1; 
              app.max_iter     = 100;
              app.MM_direction = -1;
              app.conv_wave_step    = app.wave_step;
              app.max_point_in_line = 6;
              app.x_offset          = 1;
              app.signal_max_value  = 0;
              app.show_jump         = 0;  
              
              app.haarlengthEditField.Value = app.wave_step;
              app.statrpointEditField.Value = app.x_offset;
              app.epsilonEditField.Value    = app.e_diff;
              app.stepsEditField.Value      = app.max_iter;
              app.jumpcoefEditField.Value   = app.jump_coef;
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: DownloadimageButton
        function DownloadimageButtonValueChanged(app, event)
            value = app.DownloadimageButton.Value;
            init(app);
            % Получение имени и пути к файлу
            [FileName, PathName] = uigetfile('*.bmp;*.jpg;*.png');
            % Проверка, был ли выбран файл
            if FileName~=0
                % Формирование полного пути к файлу
                FullName = [PathName FileName];
                % Считывание изображения из графического файла 
                app.Pict = imread(FullName);
                % Вывод изображения на оси
                imshow(app.Pict,'Parent',app.UIAxes);
            end
            
            app.y_dim = size(app.Pict,1);
            app.ROWSpinner.Limits = [1 app.y_dim];
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            
              global e_diff;  
              global wave_step;
              global jump_coef; 
              global max_iter;
              global MM_direction;
              global conv_wave_step;
              global max_point_in_line;
              global x_offset;
              global signal_max_value;
              global show_jump;
              
              e_diff       = app.e_diff ;  
              wave_step    = app.wave_step ;
              jump_coef    = app.jump_coef ; 
              max_iter     = app.max_iter ;
              MM_direction = app.MM_direction ;
              conv_wave_step    = app.wave_step ;
              max_point_in_line = app.max_point_in_line ;
              x_offset          = app.x_offset ;
              signal_max_value  = app.signal_max_value ;
              show_jump         = app.show_jump ;
            
            GREY_IMG = double(rgb2gray(app.Pict));
            src_arr  = GREY_IMG; 
            
            HAAR_SPACE_ARRAY  = Haar_Conv_Array(src_arr);
            
            IMG_CONTOUR = Optimization_Array(HAAR_SPACE_ARRAY, x_offset, e_diff, wave_step, jump_coef, MM_direction, max_iter );
            
            [ FIGURE, FIGURE_OFFSET_X,FIGURE_OFFSET_Y,FIGURE_WIDTH, FIGURE_HEIGHT ] = FIGURE_FROM_CONTOUR( IMG_CONTOUR );
            
            class = CLASS_BEL( FIGURE, FIGURE_WIDTH, FIGURE_HEIGHT, app.FIG_LIB_LEN, app.FIG_LIB );
            %disp(class)
            
            %bbb(class);
            cl = myNeuralNetworkFunction(class);
            disp(cl);
            %path =  ['class/' class '.png'];
            
            %picture = imread(path);
            
            %imshow(picture,'Parent',app.UIAxes2);
        end

        % Value changed function: haarlengthEditField
        function haarlengthEditFieldValueChanged(app, event)
            value = app.haarlengthEditField.Value;
            app.wave_step = value;
        end

        % Value changed function: statrpointEditField
        function statrpointEditFieldValueChanged(app, event)
            value = app.statrpointEditField.Value;
            app.x_offset = value;
        end

        % Value changed function: epsilonEditField
        function epsilonEditFieldValueChanged(app, event)
            value = app.epsilonEditField.Value;
            app.e_diff = value;
        end

        % Value changed function: stepsEditField
        function stepsEditFieldValueChanged(app, event)
            value = app.stepsEditField.Value;
            app.max_iter = value;
        end

        % Callback function
        function directionEditFieldValueChanged(app, event)
            value = app.directionEditField.Value;
            app.MM_direction = value;
        end

        % Button pushed function: PreSettingButton
        function PreSettingButtonPushed(app, event)
              app.e_diff       = 1.001;  
              app.wave_step    = 7;
              app.jump_coef    = 0.1; 
              app.max_iter     = 100;
              app.MM_direction = 1;
              app.x_offset     = 1;
        end

        % Value changed function: jumpcoefEditField
        function jumpcoefEditFieldValueChanged(app, event)
            value = app.jumpcoefEditField.Value;
            app.jump_coef = value;
        end

        % Value changed function: ROWSpinner
        function ROWSpinnerValueChanged(app, event)
            value = app.ROWSpinner.Value;
            app.ROW = value;
            
            GREY_IMG = double(rgb2gray(app.Pict));
            array = GREY_IMG(app.ROW,:);
            plot(app.UIAxes3,array,'-r');
            
            haar_row = Haar_Conv_Row(array, 1);
            
            plot(app.UIAxes4,haar_row,'-b');
                       
            max_row = Optimization_Row( haar_row, app.x_offset, app.e_diff, app.wave_step, app.MM_direction, app.max_iter);
            
            for i = 1 : length(max_row)
                p = max_row(i);
                if p > 0
                  x = p;
                  y = haar_row(x);
                  hold(app.UIAxes4, 'on');
                  plot(app.UIAxes4,x,y,'ro-');
                  hold(app.UIAxes4, 'off');
                end
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value > 0
                app.MM_direction = 1;
            else
                app.MM_direction = -1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.DownloadimageButton = uibutton(app.SettingsPanel, 'state');
            app.DownloadimageButton.ValueChangedFcn = createCallbackFcn(app, @DownloadimageButtonValueChanged, true);
            app.DownloadimageButton.Text = 'Download image';
            app.StartButton = uibutton(app.SettingsPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.haarlengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.haarlengthEditFieldLabel.Text = 'haar length';
            app.haarlengthEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.haarlengthEditField.ValueChangedFcn = createCallbackFcn(app, @haarlengthEditFieldValueChanged, true);
            app.statrpointEditFieldLabel = uilabel(app.SettingsPanel);
            app.statrpointEditFieldLabel.Text = 'statr point';
            app.statrpointEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.statrpointEditField.ValueChangedFcn = createCallbackFcn(app, @statrpointEditFieldValueChanged, true);
            app.epsilonEditFieldLabel = uilabel(app.SettingsPanel);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.epsilonEditField.ValueChangedFcn = createCallbackFcn(app, @epsilonEditFieldValueChanged, true);
            app.stepsEditFieldLabel = uilabel(app.SettingsPanel);
            app.stepsEditFieldLabel.Text = 'steps';
            app.stepsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.stepsEditField.ValueChangedFcn = createCallbackFcn(app, @stepsEditFieldValueChanged, true);
            app.PreSettingButton = uibutton(app.SettingsPanel, 'push');
            app.PreSettingButton.ButtonPushedFcn = createCallbackFcn(app, @PreSettingButtonPushed, true);
            app.PreSettingButton.Text = 'Pre Setting';
            app.jumpcoefEditFieldLabel = uilabel(app.SettingsPanel);
            app.jumpcoefEditFieldLabel.Text = 'jump coef';
            app.jumpcoefEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.jumpcoefEditField.ValueChangedFcn = createCallbackFcn(app, @jumpcoefEditFieldValueChanged, true);
            app.ROWSpinnerLabel = uilabel(app.SettingsPanel);
            app.ROWSpinnerLabel.Text = 'ROW';
            app.ROWSpinner = uispinner(app.SettingsPanel);
            app.ROWSpinner.Limits = [1 80];
            app.ROWSpinner.ValueChangedFcn = createCallbackFcn(app, @ROWSpinnerValueChanged, true);
            app.ROWSpinner.Value = 1;
            app.CheckBox = uicheckbox(app.SettingsPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.InvertLabel = uilabel(app.SettingsPanel);
            app.InvertLabel.Text = 'Invert';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes4 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end